classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        wczyt_mac_button  matlab.ui.control.Button
        wpisznumeryatrybutwoddzielonespacjamiEditFieldLabel  matlab.ui.control.Label
        wpisznumeryatrybutwoddzielonespacjamiEditField  matlab.ui.control.EditField
        gen_reg_button    matlab.ui.control.Button
        UITable           matlab.ui.control.Table
        TextArea          matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: wczyt_mac_button
        function wczyt_mac_buttonPushed(app, event)
            global fullpathname;
            [filename, pathname]=uigetfile({'*.txt'});
            fullpathname=strcat(pathname, filename);
            app.UITable.Data=wczyt_mac(fullpathname);
        end

        % Button pushed function: gen_reg_button
        function gen_reg_buttonButtonPushed(app, event)
           global fullpathname;
            nratr=(app.wpisznumeryatrybutwoddzielonespacjamiEditField.Value);

            [sms,c,rpw1,rpw2,rpw3,rpw4,rpw5,rpw6,rpw7,rpw8,rpw9,rpw10,rpw11,rpw12,rpw13,rpw14,rpw15,rpw16,rpw17,rpw18,rpw19,rpw20,rpr1,rpr2,rpr3,rpr4,rpr5,rpr6,rpr7,rpr8,rpr9,rpr10,rpr11,rpr12,rpr13,rpr14,rpr15,rpr16,rpr17,rpr18,rpr19,rpr20]=gen_reg(wczyt_mac(fullpathname),nratr);
           
            app.TextArea.Value='';
           
            n=1;
            app.TextArea.Value{n}='posortowane, unikatowe reguły dla kolejnych celów:';
            n=n+1;
            s=size(sms);
            for i=1:s(1)
                if sms(i,:)~=0
                    app.TextArea.Value{n}=num2str(sms(i,:));
                    n=n+1;
                end
            end
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw1) || ~isempty(rpr1)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(1))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw1);
                n=n+1;
                for i=1:s(1)
                    if rpw1(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw1(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr1);
                for i=1:s(1)
                    if rpr1(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr1(i,:));
                        n=n+1;
                    end
                end
            end
            %%2
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw2) || ~isempty(rpr2)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(2))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw2);
                n=n+1;
                for i=1:s(1)
                    if rpw2(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw2(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr2);
                for i=1:s(1)
                    if rpr2(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr2(i,:));
                        n=n+1;
                    end
                end
            end
            %%3
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw3) || ~isempty(rpr3)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(3))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw3);
                n=n+1;
                for i=1:s(1)
                    if rpw3(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw3(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr3);
                for i=1:s(1)
                    if rpr3(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr3(i,:));
                        n=n+1;
                    end
                end
            end
            %%4
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw4) || ~isempty(rpr4)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(4))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw4);
                n=n+1;
                for i=1:s(1)
                    if rpw4(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw4(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr4);
                for i=1:s(1)
                    if rpr4(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr4(i,:));
                        n=n+1;
                    end
                end
            end
            %%5
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw5) || ~isempty(rpr5)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(5))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw5);
                n=n+1;
                for i=1:s(1)
                    if rpw5(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw5(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr5);
                for i=1:s(1)
                    if rpr5(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr5(i,:));
                        n=n+1;
                    end
                end
            end
            %%6
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw6) || ~isempty(rpr6)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(6))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw6);
                n=n+1;
                for i=1:s(1)
                    if rpw6(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw6(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr6);
                for i=1:s(1)
                    if rpr6(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr6(i,:));
                        n=n+1;
                    end
                end
            end
            %%7
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw7) || ~isempty(rpr7)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(7))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw7);
                n=n+1;
                for i=1:s(1)
                    if rpw7(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw7(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr7);
                for i=1:s(1)
                    if rpr7(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr7(i,:));
                        n=n+1;
                    end
                end
            end
            %%8
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw8) || ~isempty(rpr8)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(8))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw8);
                n=n+1;
                for i=1:s(1)
                    if rpw8(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw8(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr8);
                for i=1:s(1)
                    if rpr8(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr8(i,:));
                        n=n+1;
                    end
                end
            end
            %%9
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw9) || ~isempty(rpr9)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(9))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw9);
                n=n+1;
                for i=1:s(1)
                    if rpw9(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw9(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr9);
                for i=1:s(1)
                    if rpr9(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr9(i,:));
                        n=n+1;
                    end
                end
            end
            %%10
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw10) || ~isempty(rpr10)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(10))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw10);
                n=n+1;
                for i=1:s(1)
                    if rpw10(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw10(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr10);
                for i=1:s(1)
                    if rpr10(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr10(i,:));
                        n=n+1;
                    end
                end
            end
            %%11
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw11) || ~isempty(rpr11)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(11))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw11);
                n=n+1;
                for i=1:s(1)
                    if rpw11(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw11(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr11);
                for i=1:s(1)
                    if rpr11(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr11(i,:));
                        n=n+1;
                    end
                end
            end
            %%12
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw12) || ~isempty(rpr12)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(12))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw12);
                n=n+1;
                for i=1:s(1)
                    if rpw12(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw12(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr12);
                for i=1:s(1)
                    if rpr12(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr12(i,:));
                        n=n+1;
                    end
                end
            end
            %%13
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw13) || ~isempty(rpr13)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(13))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw13);
                n=n+1;
                for i=1:s(1)
                    if rpw13(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw13(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr13);
                for i=1:s(1)
                    if rpr13(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr13(i,:));
                        n=n+1;
                    end
                end
            end
            %%14
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw14) || ~isempty(rpr14)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(14))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw14);
                n=n+1;
                for i=1:s(1)
                    if rpw14(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw14(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr14);
                for i=1:s(1)
                    if rpr14(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr14(i,:));
                        n=n+1;
                    end
                end
            end
            %%15
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw15) || ~isempty(rpr15)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(15))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw15);
                n=n+1;
                for i=1:s(1)
                    if rpw15(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw15(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr15);
                for i=1:s(1)
                    if rpr15(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr15(i,:));
                        n=n+1;
                    end
                end
            end
            %%16
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw16) || ~isempty(rpr16)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(16))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw16);
                n=n+1;
                for i=1:s(1)
                    if rpw16(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw16(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr16);
                for i=1:s(1)
                    if rpr16(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr16(i,:));
                        n=n+1;
                    end
                end
            end
            %%17
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw17) || ~isempty(rpr17)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(17))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw17);
                n=n+1;
                for i=1:s(1)
                    if rpw17(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw17(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr17);
                for i=1:s(1)
                    if rpr17(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr17(i,:));
                        n=n+1;
                    end
                end
            end
            %%18
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw18) || ~isempty(rpr18)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(18))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw18);
                n=n+1;
                for i=1:s(1)
                    if rpw18(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw18(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr18);
                for i=1:s(1)
                    if rpr18(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr18(i,:));
                        n=n+1;
                    end
                end
            end
            %%19
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw19) || ~isempty(rpr19)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(19))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw19);
                n=n+1;
                for i=1:s(1)
                    if rpw19(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw19(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr19);
                for i=1:s(1)
                    if rpr19(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr19(i,:));
                        n=n+1;
                    end
                end
            end
            %%20
            app.TextArea.Value{n}='';
            n=n+1;
            
            if   ~isempty(rpw20) || ~isempty(rpr20)
                app.TextArea.Value{n}=['Dla celu: ',num2str(c(20))];
                n=n+1;
                app.TextArea.Value{n}='reguły pewne:';
                s=size(rpw20);
                n=n+1;
                for i=1:s(1)
                    if rpw20(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpw20(i,:));
                        n=n+1;
                    end
                end
                app.TextArea.Value{n}='reguły przybliżone:';
                n=n+1;
                s=size(rpr20);
                for i=1:s(1)
                    if rpr20(i,:)~=0
                        app.TextArea.Value{n}=num2str(rpr20(i,:));
                        n=n+1;
                    end
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.wczyt_mac_button = uibutton(app.UIFigure, 'push');
            app.wczyt_mac_button.ButtonPushedFcn = createCallbackFcn(app, @wczyt_mac_buttonPushed, true);
            app.wczyt_mac_button.Text = 'Wczytaj macierz';
            app.wpisznumeryatrybutwoddzielonespacjamiEditFieldLabel = uilabel(app.UIFigure);
            app.wpisznumeryatrybutwoddzielonespacjamiEditFieldLabel.Text = 'wpisz numery atrybutów oddzielone spacjami ';
            app.wpisznumeryatrybutwoddzielonespacjamiEditField = uieditfield(app.UIFigure, 'text');
            app.gen_reg_button = uibutton(app.UIFigure, 'push');
            app.gen_reg_button.ButtonPushedFcn = createCallbackFcn(app, @gen_reg_buttonButtonPushed, true);
            app.gen_reg_button.Text = 'Genereuj reguły';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'A1'; 'A2'; 'A3'; 'A4'; 'A5'; 'A6'; 'A7'; 'A8'; 'A9'; 'A10'; 'A11'; 'A12'; 'A13'; 'A14'; 'A15'; 'A16'; 'A17'; 'A18'; 'A19'; 'A20'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '1'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end